classdef mainTriplexGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainApp                         matlab.ui.container.Menu
        LoadRawDataMenu                 matlab.ui.container.Menu
        LoadProcessedDataMenu           matlab.ui.container.Menu
        SetRootPathMenu                 matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        fileSelectionTab                matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        caliViewButton                  matlab.ui.control.Button
        caliLoadButton                  matlab.ui.control.Button
        caliLoadedLamp                  matlab.ui.control.Lamp
        apd3ViewButton                  matlab.ui.control.Button
        apd2ViewButton                  matlab.ui.control.Button
        apd1ViewButton                  matlab.ui.control.Button
        apd3LoadButton                  matlab.ui.control.Button
        apd3LoadedLamp                  matlab.ui.control.Lamp
        apd2LoadButton                  matlab.ui.control.Button
        apd2LoadedLamp                  matlab.ui.control.Lamp
        clearButton                     matlab.ui.control.Button
        loadDataButton                  matlab.ui.control.Button
        bgNotNeededCheckBox             matlab.ui.control.CheckBox
        bgLoadButton                    matlab.ui.control.Button
        apd1LoadButton                  matlab.ui.control.Button
        bgLoadedLamp                    matlab.ui.control.Lamp
        apd1FileEditField               matlab.ui.control.EditField
        APD1Label                       matlab.ui.control.Label
        bgFileEditField                 matlab.ui.control.EditField
        BGfileEditFieldLabel            matlab.ui.control.Label
        apd2FileEditField               matlab.ui.control.EditField
        APD2Label                       matlab.ui.control.Label
        apd3FileEditField               matlab.ui.control.EditField
        APD3Label                       matlab.ui.control.Label
        caliFileEditField               matlab.ui.control.EditField
        CalibrationLabel                matlab.ui.control.Label
        apd1LoadedLamp                  matlab.ui.control.Lamp
        dataFielesListBox               matlab.ui.control.ListBox
        dataFilesLabel                  matlab.ui.control.Label
        bg3UIAxes                       matlab.ui.control.UIAxes
        bg2UIAxes                       matlab.ui.control.UIAxes
        bg1UIAxes                       matlab.ui.control.UIAxes
        DeConSettingTab                 matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        DeConMethodDropDown             matlab.ui.control.DropDown
        DeConMethodDropDownLabel        matlab.ui.control.Label
        DatasliderSlider                matlab.ui.control.Slider
        LoadDataButton                  matlab.ui.control.Button
        RemoveBGButton                  matlab.ui.control.Button
        RequiredParametersPanel         matlab.ui.container.Panel
        SignalConditioningPanel         matlab.ui.container.Panel
        HighThresholdEditFieldLabel     matlab.ui.control.Label
        HighThresholdEditField          matlab.ui.control.NumericEditField
        LowThresholdEditFieldLabel      matlab.ui.control.Label
        LowThresholdEditField           matlab.ui.control.NumericEditField
        DataAverageDropDown             matlab.ui.control.DropDown
        DataAverageDropDownLabel        matlab.ui.control.Label
        BGRemoveIndexPanel              matlab.ui.container.Panel
        BgLowEditFieldLabel             matlab.ui.control.Label
        BgLowEditField                  matlab.ui.control.NumericEditField
        BgHighEditFieldLabel            matlab.ui.control.Label
        BgHighEditField                 matlab.ui.control.NumericEditField
        BgThreholdEditFieldLabel        matlab.ui.control.Label
        BgThreholdEditField             matlab.ui.control.NumericEditField
        DCRemoveIndexPanel              matlab.ui.container.Panel
        DcLowEditFieldLabel             matlab.ui.control.Label
        DcLowEditField                  matlab.ui.control.NumericEditField
        DcHighEditFieldLabel            matlab.ui.control.Label
        DcHighEditField                 matlab.ui.control.NumericEditField
        DigitizerTimingPanel            matlab.ui.container.Panel
        TimeResolutionnsEditFieldLabel  matlab.ui.control.Label
        TimeResolutionnsEditField       matlab.ui.control.NumericEditField
        RemoveSaturationButton          matlab.ui.control.Button
        UpsampleandTruncateButton       matlab.ui.control.Button
        AverageDataButton               matlab.ui.control.Button
        DeconButton                     matlab.ui.control.Button
        channelSelectionPanel           matlab.ui.container.Panel
        channel2CheckBox                matlab.ui.control.CheckBox
        channel3CheckBox                matlab.ui.control.CheckBox
        channel1CheckBox                matlab.ui.control.CheckBox
        AlgrithmRelatedPanel            matlab.ui.container.Panel
        LaguerreOrderEditFieldLabel     matlab.ui.control.Label
        LaguerreOrderEditField          matlab.ui.control.NumericEditField
        AlphaValueEditFieldLabel        matlab.ui.control.Label
        AlphaValueEditField             matlab.ui.control.NumericEditField
        AlphaModeDropDownLabel          matlab.ui.control.Label
        AlphaModeDropDown               matlab.ui.control.DropDown
        ChannelWidthnsLabel             matlab.ui.control.Label
        channelWidthEditField           matlab.ui.control.NumericEditField
        UIAxes_ch3BgRemoveFig           matlab.ui.control.UIAxes
        UIAxes_ch2BgRemoveFig           matlab.ui.control.UIAxes
        UIAxes_ch3DataFig               matlab.ui.control.UIAxes
        UIAxes_ch1BgRemoveFig           matlab.ui.control.UIAxes
        UIAxes_ch2DataFig               matlab.ui.control.UIAxes
        UIAxes_ch1DataFig               matlab.ui.control.UIAxes
        FittingResultTab                matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        channelDropDown                 matlab.ui.control.DropDown
        channelLabel                    matlab.ui.control.Label
        LineSlider                      matlab.ui.control.Slider
        LineSliderLabel                 matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        PlotDropDown                    matlab.ui.control.DropDown
        imgDropDownLabel                matlab.ui.control.Label
        xMinEditField                   matlab.ui.control.NumericEditField
        xMinEditFieldLabel              matlab.ui.control.Label
        xMaxEditField                   matlab.ui.control.NumericEditField
        xMaxEditFieldLabel              matlab.ui.control.Label
        SaveDataButton                  matlab.ui.control.Button
        UIAxesFitting                   matlab.ui.control.UIAxes
        UIAxesResidue                   matlab.ui.control.UIAxes
        UIAxesAutoCo                    matlab.ui.control.UIAxes
        UIAxesDeconResult               matlab.ui.control.UIAxes
        PointEditFieldLabel             matlab.ui.control.Label
        PointEditField                  matlab.ui.control.NumericEditField
        imagesReconstrctionTab          matlab.ui.container.Tab
        UIAxesImg                       matlab.ui.control.UIAxes
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        oldpath = addpath(genpath(pwd));
        
        apd1DialogApp % apd1 dialog app
        apd2DialogApp % apd1 dialog app
        apd3DialogApp % apd1 dialog app
        
    end
    
    properties (Access = public)
        irfAlignFlag = 0; % irf alignment flag
        apd1LoadedFlag =0; % apd1 load flag
        apd2LoadedFlag =0; % apd2 load flag
        apd3LoadedFlag =0; % apd3 load flag
        bgLoadedFlag = 0; % bg load flag
        caliLoadedFlag = 0; % calibratin loaded flag
        rootFolder = fileparts(mfilename('fullpath')) % current working directory
        dataInfoObj = dataInfo('','','','','','','','','','','','',NaN);% Description
        dataNameList = [];
        dataFolder = pwd;
        detectorDataFolder = pwd;
        apd1Obj = [];
        apd2Obj = [];
        apd3Obj = [];
        irfName = [];
        bgObj = [];
        caliObj = [];
        bg_file_path = [];
        nWaveformToPlot = 100;
        nStep; % plot every nStep measurement
        
        dataLoadedFlag = 0; % data loaded flag
        rawDataObj % Description
        dataPlotHandle % handle for data lines
        BgPlotHandle % Description
        DcLowLineCh1Handle % Description
        DcLowLineCh2Handle % Description
        DcLowLineCh3Handle % Description
        
        DcHighLineCh1Handle % Description
        DcHighLineCh2Handle % Description
        DcHighLineCh3Handle % Description
        
        BgLowLineCh1Handle % Description
        BgLowLineCh2Handle % Description
        BgLowLineCh3Handle % Description
        
        BgHighLineCh1Handle % Description
        BgHighLineCh2Handle % Description
        BgHighLineCh3Handle % Description
        
        ChDataArray;
        Ch1Obj; % Description
        Ch2Obj; % Description
        Ch3Obj; % Description
        Ch4Obj; % Description
        
        ChLaguerreArray;
        Ch1LaguerreObj % Description
        Ch2LaguerreObj
        Ch3LaguerreObj
        Ch4LaguerreObj
        
        deconChannelSelection% Description
        nPointsPerLine % Description
        nLines
        deconResutlObj % Description
        Ch1PeakLineHandle = []; % Description
        Ch2PeakLineHandle = [];
        Ch3PeakLineHandle = [];
        Ch4PeakLineHandle = [];
        
        FLImDataObj % FLIm data class object
        Ch1DataObj % channel 1 ChannelDataAPD Obj
        Ch2DataObj % channel 1 ChannelDataAPD Obj
        Ch3DataObj % channel 1 ChannelDataAPD Obj
    end
    
    %--------------------------------------------------------------------------
    
    methods (Access = public)
        
        function plotData(app,type)
            numOfWFtoPlot = app.nWaveformToPlot;
            plotStep = size(app.Ch1DataObj.preProcessedData,2)/numOfWFtoPlot;
            plotStep = floor(plotStep);
            temp = sortData(app.Ch1DataObj, 'ascend');
            plot(app.UIAxes_ch1DataFig,temp(:,1:plotStep:end))
            app.UIAxes_ch1DataFig.YLim = [0 2];
            temp = sortData(app.Ch2DataObj, 'ascend');
            plot(app.UIAxes_ch2DataFig,temp(:,1:plotStep:end))
            app.UIAxes_ch2DataFig.YLim = [0 2];
            temp = sortData(app.Ch2DataObj, 'ascend');
            plot(app.UIAxes_ch3DataFig,temp(:,1:plotStep:end))
            app.UIAxes_ch3DataFig.YLim = [0 2];
            switch type
                case 'raw'
                    titleText1 = ['Ch1 raw FLIm data (no averaging) ' num2str(numOfWFtoPlot) ' waveforms'];
                    titleText2 = ['Ch2 raw FLIm data (no averaging) ' num2str(numOfWFtoPlot) ' waveforms'];
                    titleText3 = ['Ch3 raw FLIm data (no averaging) ' num2str(numOfWFtoPlot) ' waveforms'];
                case 'filtered'
                    titleText1 = ['Ch1 filtered raw FLIm data (no averaging) ' num2str(numOfWFtoPlot) ' waveforms'];
                    titleText2 = ['Ch2 filtered raw FLIm data (no averaging) ' num2str(numOfWFtoPlot) ' waveforms'];
                    titleText3 = ['Ch3 filtered raw FLIm data (no averaging) ' num2str(numOfWFtoPlot) ' waveforms'];
                case 'averaged'
                    titleText1 = ['Ch1 averaged FLIm data (' num2str(app.Ch1DataObj.dataAveraging) ' averaging) ' num2str(numOfWFtoPlot) ' waveforms'];
                    titleText2 = ['Ch2 averaged FLIm data (' num2str(app.Ch2DataObj.dataAveraging) ' averaging) ' num2str(numOfWFtoPlot) ' waveforms'];
                    titleText3 = ['Ch3 averaged FLIm data (' num2str(app.Ch3DataObj.dataAveraging) ' averaging) ' num2str(numOfWFtoPlot) ' waveforms'];
            end
            title(app.UIAxes_ch1DataFig,titleText1)
            title(app.UIAxes_ch2DataFig,titleText2)
            title(app.UIAxes_ch3DataFig,titleText3)
            
        end
        
        function plotBg(app)
            if ~isempty(app.bgObj)
                app.BgPlotHandle = plot(app.UIAxes_ch2DataFig,app.bgObj.bg,'b.--','MarkerSize',8);
                ylim(app.UIAxes_ch2DataFig,[-0.05 0.75])
            else
                
                text(app.UIAxes_ch2DataFig,50,0.5,'No Background!')
            end
        end
        
        function plotDcMarker(app)
            if ~isempty(app.DcLowLineCh1Handle)
                delete(app.DcLowLineCh1Handle)
            end
            if ~isempty(app.DcLowLineCh2Handle)
                delete(app.DcLowLineCh2Handle)
            end
            if ~isempty(app.DcLowLineCh3Handle)
                delete(app.DcLowLineCh3Handle)
            end
            if ~isempty(app.DcHighLineCh1Handle)
                delete(app.DcHighLineCh1Handle)
            end
            if ~isempty(app.DcHighLineCh2Handle)
                delete(app.DcHighLineCh2Handle)
            end
            if ~isempty(app.DcHighLineCh3Handle)
                delete(app.DcHighLineCh3Handle)
            end
            
            lowIdx = app.DcLowEditField.Value;
            highIdx = app.DcHighEditField.Value;
            app.DcLowLineCh1Handle = line(app.UIAxes_ch1DataFig,[lowIdx lowIdx],[app.UIAxes_ch1DataFig.YLim],'Color','g','LineStyle','--','LineWidth',1); % plot on data preview figure
            app.DcHighLineCh1Handle = line(app.UIAxes_ch1DataFig,[highIdx highIdx],[app.UIAxes_ch1DataFig.YLim],'Color','g','LineStyle','--','LineWidth',1) ; %  plot on data preview figure
            
            app.DcLowLineCh2Handle = line(app.UIAxes_ch2DataFig,[lowIdx lowIdx],[app.UIAxes_ch2DataFig.YLim],'Color','g','LineStyle','--','LineWidth',1); % plot on BG figure
            app.DcHighLineCh2Handle = line(app.UIAxes_ch2DataFig,[highIdx highIdx],[app.UIAxes_ch2DataFig.YLim],'Color','g','LineStyle','--','LineWidth',1);  % plot on BG figure
            
            app.DcLowLineCh3Handle = line(app.UIAxes_ch3DataFig,[lowIdx lowIdx],[app.UIAxes_ch3DataFig.YLim],'Color','g','LineStyle','--','LineWidth',1); % plot on data preview figure
            app.DcHighLineCh3Handle = line(app.UIAxes_ch3DataFig,[highIdx highIdx],[app.UIAxes_ch3DataFig.YLim],'Color','g','LineStyle','--','LineWidth',1) ; %  plot on data preview figure
        end
        
        function plotBgMarker(app)
            
            if ~isempty(app.BgLowLineCh1Handle)
                delete(app.BgLowLineCh1Handle)
            end
            if ~isempty(app.BgLowLineCh2Handle)
                delete(app.BgLowLineCh2Handle)
            end
            if ~isempty(app.BgLowLineCh3Handle)
                delete(app.BgLowLineCh3Handle)
            end
            
            if ~isempty(app.BgHighLineCh1Handle)
                delete(app.BgHighLineCh1Handle)
            end
            if ~isempty(app.BgHighLineCh2Handle)
                delete(app.BgHighLineCh2Handle)
            end
            if ~isempty(app.BgHighLineCh3Handle)
                delete(app.BgHighLineCh3Handle)
            end
            
            
            lowIdx = app.BgLowEditField.Value;
            highIdx = app.BgHighEditField.Value;
            app.BgLowLineCh1Handle = line(app.UIAxes_ch1DataFig,[lowIdx lowIdx],[app.UIAxes_ch1DataFig.YLim],'Color','r','LineStyle','--','LineWidth',1); % plot on data preview figure
            app.BgLowLineCh1Handle = line(app.UIAxes_ch1DataFig,[highIdx highIdx],[app.UIAxes_ch1DataFig.YLim],'Color','r','LineStyle','--','LineWidth',1) ; %  plot on data preview figure
            
            app.BgLowLineCh2Handle = line(app.UIAxes_ch2DataFig,[lowIdx lowIdx],[app.UIAxes_ch2DataFig.YLim],'Color','r','LineStyle','--','LineWidth',1); % plot on data preview figure
            app.BgLowLineCh2Handle = line(app.UIAxes_ch2DataFig,[highIdx highIdx],[app.UIAxes_ch2DataFig.YLim],'Color','r','LineStyle','--','LineWidth',1) ; %  plot on data preview figure
            
            app.BgLowLineCh3Handle = line(app.UIAxes_ch3DataFig,[lowIdx lowIdx],[app.UIAxes_ch3DataFig.YLim],'Color','r','LineStyle','--','LineWidth',1); % plot on data preview figure
            app.BgLowLineCh3Handle = line(app.UIAxes_ch3DataFig,[highIdx highIdx],[app.UIAxes_ch3DataFig.YLim],'Color','r','LineStyle','--','LineWidth',1) ; %  plot on data preview figure
            
        end
        
        function plotPreprocessedData(app)
            numOfWFtoPlot = app.nWaveformToPlot;
            plotStep = size(app.Ch1DataObj.preProcessedData,2)/numOfWFtoPlot;
            plotStep = floor(plotStep);
            temp = sortData(app.Ch1DataObj, 'ascend');
            plot(app.UIAxes_ch1BgRemoveFig,temp(:,1:plotStep:end));
            ylim(app.UIAxes_ch1BgRemoveFig,[-0.1 2])
            title(app.UIAxes_ch1BgRemoveFig,'Ch1 DC&BG removed waveforms (100 Waveform)')
            temp = sortData(app.Ch2DataObj, 'ascend');
            plot(app.UIAxes_ch2BgRemoveFig,temp(:,1:plotStep:end));
            ylim(app.UIAxes_ch2BgRemoveFig,[-0.1 2])
            title(app.UIAxes_ch2BgRemoveFig,'Ch2 DC&BG removed waveforms (100 Waveform)')
            temp = sortData(app.Ch3DataObj, 'ascend');
            plot(app.UIAxes_ch3BgRemoveFig,temp(:,1:plotStep:end));
            ylim(app.UIAxes_ch3BgRemoveFig,[-0.1 2])
            title(app.UIAxes_ch3BgRemoveFig,'Ch3 DC&BG removed waveforms (100 Waveform)')
        end
        
        function plotTruncatedData(app)
            numOfWFtoPlot = app.nWaveformToPlot;
            plotStep = size(app.Ch1DataObj.dataT,2)/numOfWFtoPlot;
            plotStep = floor(plotStep);
            temp = sortTruncatedData(app.Ch1DataObj, 'ascend');
            plot(app.UIAxes_ch1BgRemoveFig,temp(:,1:plotStep:end));
            ylim(app.UIAxes_ch1BgRemoveFig,[-0.1 2])
            title(app.UIAxes_ch1BgRemoveFig,'Ch1 DC&BG removed truncated waveforms (100 Waveform)')
            temp = sortTruncatedData(app.Ch2DataObj, 'ascend');
            plot(app.UIAxes_ch2BgRemoveFig,temp(:,1:plotStep:end));
            ylim(app.UIAxes_ch2BgRemoveFig,[-0.1 2])
            title(app.UIAxes_ch2BgRemoveFig,'Ch2 DC&BG removed truncated waveforms (100 Waveform)')
            temp = sortTruncatedData(app.Ch3DataObj, 'ascend');
            plot(app.UIAxes_ch3BgRemoveFig,temp(:,1:plotStep:end));
            ylim(app.UIAxes_ch3BgRemoveFig,[-0.1 2])
            title(app.UIAxes_ch3BgRemoveFig,'Ch3 DC&BG removed truncated waveforms (100 Waveform)')
        end
        
        function plotPeakLocations(app,peaks)
            if ~isempty(app.Ch1PeakLineHandle)
                delete (app.Ch1PeakLineHandle)
            end
            if ~isempty(app.Ch2PeakLineHandle)
                delete (app.Ch2PeakLineHandle)
            end
            if ~isempty(app.Ch3PeakLineHandle)
                delete (app.Ch3PeakLineHandle)
            end
            if ~isempty(app.Ch4PeakLineHandle)
                delete (app.Ch4PeakLineHandle)
            end
            app.Ch1PeakLineHandle = line(app.UIAxes_ch1BgRemoveFig,[peaks(1) peaks(1)],[app.UIAxes_ch1DataFig.YLim],'Color','m','LineStyle','--','LineWidth',2);
            app.Ch2PeakLineHandle = line(app.UIAxes_ch1BgRemoveFig,[peaks(2) peaks(2)],[app.UIAxes_ch1DataFig.YLim],'Color','g','LineStyle','--','LineWidth',2);
            app.Ch3PeakLineHandle = line(app.UIAxes_ch1BgRemoveFig,[peaks(3) peaks(3)],[app.UIAxes_ch1DataFig.YLim],'Color','y','LineStyle','--','LineWidth',2);
            app.Ch4PeakLineHandle = line(app.UIAxes_ch1BgRemoveFig,[peaks(4) peaks(4)],[app.UIAxes_ch1DataFig.YLim],'Color','r','LineStyle','--','LineWidth',2);
            
        end
        
        function plotChannelData(app,axes,chObj)
            
            pData = plot(axes,chObj.data(:,1:app.nStep:end));
            hold(axes,'on')
            pIRF = plot(axes,max(max(chObj.data(:,1:app.nStep:end)))/max(chObj.iIRF).*chObj.iIRF,'m','LineWidth',2);
            hold(axes,'off')
            for i = 1:length(pData)
                set(get(get(pData(i),'Annotation'),'LegendInformation'),'IconDisplayStyle','off');
            end
            legend(axes,{'IRF'})
            
        end
        
        function plotDeconResult(app)
            %get channel number
            channel = app.channelDropDown.Value;
            %get laguerre object
            switch channel
                case 'Channel 1'
                    plotObj = app.Ch1DataObj;
                case 'Channel 2'
                    plotObj = app.Ch2DataObj;
                case 'Channel 3'
                    plotObj = app.Ch3DataObj;
            end
            
            %get line number
            app.nLines = 2;
            % setup line slider bar
            app.LineSlider.Limits = [1 app.nLines];
            app.LineSlider.Value = 1;
            %get num of points
            %             app.nPointsPerLine = size(plotObj.averagedData,2);
            app.nPointsPerLine = size(plotObj.dataT,2);
            % setup points slider bar
            app.Slider.Limits = [1 app.nPointsPerLine];
            %             app.Slider.Value = 1;
            % get line index
            lineIdx = app.LineSlider.Value;
            lineIdx = 1; % set lineIdx to 1
            % get point index
            pointIdx = app.Slider.Value;
            % calculate linear index
            
            Idx = pointIdx;
            % get goodness of fit and set line color
            try
                gof = plotObj.stat_test.autoCorr.h(Idx); % goodness of fit to filter data
            catch
                gof=1;
            end
            if gof
                rawColor = [0 0 1]; % if fit is good, use blue
            else
                rawColor = [0.5 0.5 0.5];% if fit is not good, use grey
            end
            
            %             autoCorrToPlot = LaguerreObj.stat_test.autoCorr.autoCorrCurve{Idx};
            rawDataToPlot = get(plotObj,'wf_aligned',Idx);
            rawDataMax = max(rawDataToPlot);
            fitToPlot = get(plotObj,'fit',Idx);
            %             decayToPlot = allDecay(:,Idx);
            residueToPlot = rawDataToPlot-fitToPlot;
            gain = plotObj.gainDecon(Idx);
            CtrlV = plotObj.CtrlVDecon(Idx);
            LT =  plotObj.LTs(Idx);
            
            irfV = plotObj.APDObj.irfV;
            irfIdx = ceil(interp1(irfV, 1:length(irfV),CtrlV));
            
            irf = plotObj.APDObj.irfT(:,irfIdx);
            
            t = plotObj.dtUp*(1:length(rawDataToPlot))';
            plot(app.UIAxesFitting,t,rawDataToPlot,'.-','Color', rawColor,'LineWidth', 1, 'MarkerSize',10)
            hold(app.UIAxesFitting,'on')
            plot(app.UIAxesFitting,t(1:length(irf)),rawDataMax/max(irf)*irf,'g','LineWidth', 2)
            plot(app.UIAxesFitting,t,fitToPlot,'m','LineWidth', 1)
            hold(app.UIAxesFitting,'off')
            
            lgd = legend(app.UIAxesFitting,'Raw Data', 'iRF', 'Fitting');
            lgd.FontSize = 8;
            title_temp = sprintf('Line %2d, Point %2d, Lifetime %3.2f, Gain %4.0f.',lineIdx,pointIdx,LT,gain);
            title_temp = [channel title_temp];
            title(app.UIAxesFitting,title_temp)
            ylim(app.UIAxesFitting,[-0.4 plotObj.dataHigh+0.1])
            xlim(app.UIAxesFitting,[app.xMinEditField.Value app.xMaxEditField.Value])
            
            
            %
            normResidueToPlot = residueToPlot./rawDataMax*100;
            plot(app.UIAxesResidue,t,normResidueToPlot,'r.-', 'LineWidth', 1,'MarkerSize',10);
            try
                ylim(app.UIAxesResidue,[-max(max(norm_residue_to_plot), abs(min(norm_residue_to_plot))) max(max(norm_residue_to_plot), abs(min(norm_residue_to_plot)))]);
            end
            xlim(app.UIAxesResidue,[app.xMinEditField.Value app.xMaxEditField.Value])
            title(app.UIAxesResidue,'Residual (%)')
            
            %plot auto correlation
            autoCorrToPlot = xcorr(residueToPlot, 'coeff');
            plot(app.UIAxesAutoCo, t, autoCorrToPlot(length(t):end), 'm.-', 'LineWidth', 2)
            % setup upper and lower bound of auto-correlation
            bounds(1) = 2 / sqrt(length(rawDataToPlot));
            bounds(2) = -bounds(1);
            hold(app.UIAxesAutoCo,'on')
            plot(app.UIAxesAutoCo, t, (bounds(1) * ones(size(t))), 'k--')
            plot(app.UIAxesAutoCo, t, (bounds(2) * ones(size(t))), 'k--')
            hold(app.UIAxesAutoCo,'off')
            ylabel(app.UIAxesAutoCo,'Res. Autocorrelation (a.u.)');
            try
                ylim([-max(max(autocorr_to_plot), abs(min(autocorr_to_plot))) max(max(autocorr_to_plot), abs(min(autocorr_to_plot)))]);
            end
            xlim(app.UIAxesAutoCo,[app.xMinEditField.Value app.xMaxEditField.Value])
            
        end
        
        function plotImg(app)
            %get channel number
            channel = app.deconChannelSelection(str2double(app.channelDropDown.Value));
            %get laguerre object
            
            imgLT = app.deconResutlObj.ltMap{channel};
            imgLT = imgLT;
            imagesc(app.UIAxesImg,imgLT)
            hold(app.UIAxesImg,'on')
            plot(app.UIAxesImg,app.Slider.Value,app.LineSlider.Value,'w+', 'MarkerSize', 20,'LineWidth',3);
            hold(app.UIAxesImg,'off')
            xlim(app.UIAxesImg,[1 app.nPointsPerLine])
            ylim(app.UIAxesImg,[1 app.nLines])
            %             axis(app.UIAxesImg,'equal')
            colormap(app.UIAxesImg,'jet')
            colorbar(app.UIAxesImg)
            
        end
        
        
        function updateChannelDropDown(app)
            dropDownList = cell(1);
            if app.channel1CheckBox.Value
                dropDownList = [dropDownList; app.channel1CheckBox.Text];
            end
            if app.channel2CheckBox.Value
                dropDownList = [dropDownList; app.channel2CheckBox.Text];
            end
            if app.channel3CheckBox.Value
                dropDownList = [dropDownList; app.channel3CheckBox.Text];
            end
            app.channelDropDown.Items = dropDownList(2:end);
        end
        
        function clearAllPreviewAxis(app)
            cla(app.UIAxes_ch1DataFig)
            cla(app.UIAxes_ch2DataFig)
            cla(app.UIAxes_ch3DataFig)
            cla(app.UIAxes_ch1BgRemoveFig)
            cla(app.UIAxes_ch2BgRemoveFig)
            cla(app.UIAxes_ch3BgRemoveFig)
        end
        
        function saveAPDDetectorPath(app)
            ini = IniConfig();
            ini.AddSections({'APD Detector Temp Path'});
            ini.AddKeys('APD Detector Temp Path', 'ADP1', app.apd1FileEditField.Value);
            ini.AddKeys('APD Detector Temp Path', 'ADP2', app.apd2FileEditField.Value);
            ini.AddKeys('APD Detector Temp Path', 'ADP3', app.apd3FileEditField.Value);
            ini.WriteFile(fullfile(app.rootFolder,'Preprocessing_APD','DetectorPathTamp.ini'));
        end
        
        function saveDeconSettingIni(app)
            ini = IniConfig();
            ini.AddSections({'DeCon Parameter'});
            ini.AddKeys('DeCon Parameter', 'Time Resolution (ns)', app.TimeResolutionnsEditField.Value);
            ini.AddKeys('DeCon Parameter', 'Channel Width (ns)', app.channelWidthEditField.Value);
            ini.AddKeys('DeCon Parameter', 'Signal Low (V)', app.LowThresholdEditField.Value);
            ini.AddKeys('DeCon Parameter', 'Signal High (V)', app.HighThresholdEditField.Value);
            ini.AddKeys('DeCon Parameter', 'Laguerre Order', app.LaguerreOrderEditField.Value);
            ini.AddKeys('DeCon Parameter', 'Alpha Mode', app.AlphaModeDropDown.Value);
            ini.AddKeys('DeCon Parameter', 'Alpha Value', app.AlphaValueEditField.Value);
            ini.AddKeys('DeCon Parameter', 'Deconvolve Channel 1?', int64(app.channel1CheckBox.Value));
            ini.AddKeys('DeCon Parameter', 'Deconvolve Channel 2?', int64(app.channel2CheckBox.Value));
            ini.AddKeys('DeCon Parameter', 'Deconvolve Channel 3?', int64(app.channel3CheckBox.Value));
            ini.AddKeys('DeCon Parameter', 'BG Index Low', app.BgLowEditField.Value);
            ini.AddKeys('DeCon Parameter', 'BG Index High', app.BgHighEditField.Value);
            ini.AddKeys('DeCon Parameter', 'DC Index Low', app.DcLowEditField.Value);
            ini.AddKeys('DeCon Parameter', 'DC Index High', app.DcHighEditField.Value);
            ini.WriteFile(fullfile(app.rootFolder,'Preprocessing_APD','DeConSetting.ini'));
        end
        
        function setAPDDetectorPath(app)
            %-------------------read APD detector file---------------------
            ini = IniConfig();
            ini.ReadFile('DetectorPathTamp.ini');
            sections = ini.GetSections();
            [keys, ~] = ini.GetKeys(sections{1});
            APDPath = ini.GetValues(sections{1}, keys);
            % load ch1 APD file
            try
                app.apd1Obj = apdClass(APDPath{1});
                creatFromPath(app.apd1Obj);
                app.apd1LoadedLamp.Color = 'g';
                app.apd1LoadedFlag = 1;
                app.apd1FileEditField.Value = app.apd1Obj.filePath;
                app.apd1ViewButton.Enable = 'On'; % enable view button
                [filepath,name,ext] = fileparts(APDPath{1});
                app.dataInfoObj.apd1Name = [name ext];
                app.dataInfoObj.apd1Folder = filepath;
            catch
                warndlg('Could not load channel 1 APD detector file, please manually by clicking load button');
                app.apd1FileEditField.Value = 'Please Select Channel 1 APD File!';
            end
            %load ch2 APD file
            try
                app.apd2Obj = apdClass(APDPath{2});
                creatFromPath(app.apd2Obj); % load 1st, if error throw warning
                app.apd2LoadedLamp.Color = 'g';
                app.apd2LoadedFlag = 1;
                app.apd2FileEditField.Value = app.apd2Obj.filePath;
                app.apd2ViewButton.Enable = 'On'; % enable view button
                [filepath,name,ext] = fileparts(APDPath{2}); %
                app.dataInfoObj.apd2Name = [name ext];
                app.dataInfoObj.apd2Folder = filepath;
            catch
                warndlg('Could not load channel 2 APD detector file, please manually by clicking load button');
                app.apd2FileEditField.Value = 'Please Select Channel 2 APD File!';
            end
            
            try
                app.apd3Obj = apdClass(APDPath{3});
                creatFromPath(app.apd3Obj);
                app.apd3LoadedLamp.Color = 'g';
                app.apd3LoadedFlag = 1;
                app.apd3FileEditField.Value = app.apd3Obj.filePath;
                app.apd3ViewButton.Enable = 'On'; % enable view button
                [filepath,name,ext] = fileparts(APDPath{3}); %
                app.dataInfoObj.apd3Name = [name ext];
                app.dataInfoObj.apd3Folder = filepath;
            catch
                warndlg('Could not load channel 3 APD detector file, please manually by clicking load button');
                app.apd3FileEditField.Value = 'Please Select Channel 3 APD File!';
            end
            %--------------------------------------------------------------
        end
        
        function loadDeConSettingIni(app)
            ini = IniConfig();
            ini.ReadFile('DeConSetting.ini');
            sections = ini.GetSections();
            [keys, ~] = ini.GetKeys(sections{1});
            DeConSettings = ini.GetValues(sections{1}, keys);
            app.TimeResolutionnsEditField.Value = DeConSettings{1};
            app.channelWidthEditField.Value = DeConSettings{2};
            app.LowThresholdEditField.Value = DeConSettings{3};
            app.HighThresholdEditField.Value = DeConSettings{4};
            app.LaguerreOrderEditField.Value = DeConSettings{5};
            app.AlphaModeDropDown.Value = DeConSettings{6};
            app.AlphaValueEditField.Value = DeConSettings{7};
            app.channel1CheckBox.Value = DeConSettings{8};
            app.channel2CheckBox.Value = DeConSettings{9};
            app.channel3CheckBox.Value = DeConSettings{10};
            app.BgLowEditField.Value = DeConSettings{11};
            app.BgHighEditField.Value = DeConSettings{12};
            app.DcLowEditField.Value = DeConSettings{13};
            app.DcHighEditField.Value = DeConSettings{14};
        end
    end
    
    methods (Access = private)
        
        function uiUpdate(app,step)
            switch step
                case 'init'
                    % diable
                    app.LowThresholdEditField.Enable = 0;
                    app.HighThresholdEditField.Enable = 0;
                    app.DataAverageDropDown.Enable = 0;
                    app.BgThreholdEditField.Enable = 0;
                    app.BgHighEditField.Enable = 0;
                    app.BgLowEditField.Enable = 0;
                    app.DcLowEditField.Enable = 0;
                    app.DcHighEditField.Enable = 0;
                    app.TimeResolutionnsEditField.Enable = 0;
                    app.channelWidthEditField.Enable = 0;
                    app.channel1CheckBox.Enable = 0;
                    app.channel2CheckBox.Enable = 0;
                    app.channel3CheckBox.Enable = 0;
                    app.LaguerreOrderEditField.Enable = 0;
                    app.AlphaModeDropDown.Enable = 0;
                    app.AlphaValueEditField.Enable = 0;
                    app.RemoveSaturationButton.Enable = 0;
                    app.AverageDataButton.Enable = 0;
                    app.RemoveBGButton.Enable = 0;
                    app.UpsampleandTruncateButton.Enable = 0;
                    app.DeconButton.Enable = 0;
                    % enable
                    app.LoadDataButton.Enable = 1;
                    
                case 'dataLoaded'
                    % diable
                    app.DataAverageDropDown.Enable = 0;
                    app.BgThreholdEditField.Enable = 0;
                    app.BgHighEditField.Enable = 0;
                    app.BgLowEditField.Enable = 0;
                    app.DcLowEditField.Enable = 0;
                    app.DcHighEditField.Enable = 0;
                    app.TimeResolutionnsEditField.Enable = 0;
                    app.channelWidthEditField.Enable = 0;
                    app.channel1CheckBox.Enable = 0;
                    app.channel2CheckBox.Enable = 0;
                    app.channel3CheckBox.Enable = 0;
                    app.LaguerreOrderEditField.Enable = 0;
                    app.AlphaModeDropDown.Enable = 0;
                    app.AlphaValueEditField.Enable = 0;
                    app.AverageDataButton.Enable = 0;
                    app.RemoveBGButton.Enable = 0;
                    app.UpsampleandTruncateButton.Enable = 0;
                    app.DeconButton.Enable = 0;
                    % enable
                    app.LoadDataButton.Enable = 1;
                    app.LowThresholdEditField.Enable = 1;
                    app.HighThresholdEditField.Enable = 1;
                    app.RemoveSaturationButton.Enable = 1;
                    
                case 'removedSat'
                    % diable
                    app.LowThresholdEditField.Enable = 0;
                    app.HighThresholdEditField.Enable = 0;
                    app.RemoveSaturationButton.Enable = 0;
                    app.BgThreholdEditField.Enable = 0;
                    app.BgHighEditField.Enable = 0;
                    app.BgLowEditField.Enable = 0;
                    app.DcLowEditField.Enable = 0;
                    app.DcHighEditField.Enable = 0;
                    app.TimeResolutionnsEditField.Enable = 0;
                    app.channelWidthEditField.Enable = 0;
                    app.channel1CheckBox.Enable = 0;
                    app.channel2CheckBox.Enable = 0;
                    app.channel3CheckBox.Enable = 0;
                    app.LaguerreOrderEditField.Enable = 0;
                    app.AlphaModeDropDown.Enable = 0;
                    app.AlphaValueEditField.Enable = 0;
                    app.RemoveBGButton.Enable = 0;
                    app.UpsampleandTruncateButton.Enable = 0;
                    app.DeconButton.Enable = 0;
                    % enable
                    app.LoadDataButton.Enable = 1;
                    app.DataAverageDropDown.Enable = 1;
                    app.AverageDataButton.Enable = 1;
                case 'dataAveraged'
                    % diable
                    app.LowThresholdEditField.Enable = 0;
                    app.HighThresholdEditField.Enable = 0;
                    app.RemoveSaturationButton.Enable = 0;
                    app.BgThreholdEditField.Enable = 0;
                    app.BgHighEditField.Enable = 1;
                    app.BgLowEditField.Enable = 1;
                    app.DcLowEditField.Enable = 1;
                    app.DcHighEditField.Enable = 1;
                    app.TimeResolutionnsEditField.Enable = 0;
                    app.channelWidthEditField.Enable = 0;
                    app.channel1CheckBox.Enable = 0;
                    app.channel2CheckBox.Enable = 0;
                    app.channel3CheckBox.Enable = 0;
                    app.LaguerreOrderEditField.Enable = 0;
                    app.AlphaModeDropDown.Enable = 0;
                    app.AlphaValueEditField.Enable = 0;
                    app.AverageDataButton.Enable = 0;
                    app.RemoveBGButton.Enable = 1;
                    app.UpsampleandTruncateButton.Enable = 0;
                    app.DeconButton.Enable = 0;
                    app.DataAverageDropDown.Enable = 0;
                    app.LoadDataButton.Enable = 1;
                case 'BGRemoved'
                    % diable
                    app.LowThresholdEditField.Enable = 0;
                    app.HighThresholdEditField.Enable = 0;
                    app.RemoveSaturationButton.Enable = 0;
                    app.BgThreholdEditField.Enable = 0;
                    app.BgHighEditField.Enable = 0;
                    app.BgLowEditField.Enable = 0;
                    app.DcLowEditField.Enable = 0;
                    app.DcHighEditField.Enable = 0;
                    app.TimeResolutionnsEditField.Enable = 0;
                    app.channelWidthEditField.Enable = 1;
                    app.channel1CheckBox.Enable = 0;
                    app.channel2CheckBox.Enable = 0;
                    app.channel3CheckBox.Enable = 0;
                    app.LaguerreOrderEditField.Enable = 1;
                    app.AlphaModeDropDown.Enable = 0;
                    app.AlphaValueEditField.Enable = 1;
                    app.AverageDataButton.Enable = 0;
                    app.RemoveBGButton.Enable = 0;
                    app.UpsampleandTruncateButton.Enable = 1;
                    app.DeconButton.Enable = 0;
                    app.DataAverageDropDown.Enable = 0;
                    app.LoadDataButton.Enable = 1;
                case 'truncated'
                    % diable
                    app.LowThresholdEditField.Enable = 0;
                    app.HighThresholdEditField.Enable = 0;
                    app.RemoveSaturationButton.Enable = 0;
                    app.BgThreholdEditField.Enable = 0;
                    app.BgHighEditField.Enable = 0;
                    app.BgLowEditField.Enable = 0;
                    app.DcLowEditField.Enable = 0;
                    app.DcHighEditField.Enable = 0;
                    app.TimeResolutionnsEditField.Enable = 0;
                    app.channelWidthEditField.Enable = 0;
                    app.channel1CheckBox.Enable = 0;
                    app.channel2CheckBox.Enable = 0;
                    app.channel3CheckBox.Enable = 0;
                    app.LaguerreOrderEditField.Enable = 0;
                    app.AlphaModeDropDown.Enable = 0;
                    app.AlphaValueEditField.Enable = 0;
                    app.AverageDataButton.Enable = 0;
                    app.RemoveBGButton.Enable = 0;
                    app.UpsampleandTruncateButton.Enable = 0;
                    app.DeconButton.Enable = 1;
                    app.DataAverageDropDown.Enable = 0;
                    app.LoadDataButton.Enable = 1;
                    
                    
            end
            
        end
        
        function clearAllFittingResultPlot(app)
            cla(app.UIAxesFitting)
            cla(app.UIAxesResidue)
            cla(app.UIAxesAutoCo)
            cla(app.UIAxesDeconResult)
        end
        
        function plotTrace(app,option)
            switch option
                case 'Decon Intensity'
                    plot(app.UIAxesDeconResult,app.Ch1DataObj.INTsAllGainCorrected,'.-', 'Color',[131,0,181]/255)
                    hold(app.UIAxesDeconResult,'on')
                    plot(app.UIAxesDeconResult,app.Ch2DataObj.INTsAllGainCorrected,'.-', 'Color',[0,70,255]/255)
                    plot(app.UIAxesDeconResult,app.Ch3DataObj.INTsAllGainCorrected,'.-', 'Color',[0,255,146]/255)
                    hold(app.UIAxesDeconResult,'off')
                    legend(app.UIAxesDeconResult,'Ch1','Ch2','Ch3')
                    xlabel(app.UIAxesDeconResult,'Points')
                    ylabel(app.UIAxesDeconResult,'Decon Intensity (a.u.)')
                    title(app.UIAxesDeconResult,'Decon Intensity Trace Decon Data Points Gain Corrected')
                case 'Raw Intensity'
                    INT1 = sum(app.Ch1DataObj.preProcessedData)';
                    INT2= sum(app.Ch2DataObj.preProcessedData)';
                    INT3 = sum(app.Ch3DataObj.preProcessedData)';
                    INT1GC = INT1./app.Ch1DataObj.gainDecon';
                    INT2GC = INT2./app.Ch2DataObj.gainDecon';
                    INT3GC = INT3./app.Ch3DataObj.gainDecon';
                    plot(app.UIAxesDeconResult,INT1GC,'.-', 'Color',[131,0,181]/255)
                    hold(app.UIAxesDeconResult,'on')
                    plot(app.UIAxesDeconResult,INT2GC,'.-', 'Color',[0,70,255]/255)
                    plot(app.UIAxesDeconResult,INT3GC,'.-', 'Color',[0,255,146]/255)
                    hold(app.UIAxesDeconResult,'off')
                    legend(app.UIAxesDeconResult,'Ch1','Ch2','Ch3')
                    xlabel(app.UIAxesDeconResult,'Points')
                    ylabel(app.UIAxesDeconResult,'Raw Intensity (a.u.)')
                    title(app.UIAxesDeconResult,'Raw Intensity Trace Decon Data Points Gain Corrected')
                case 'Gain'
                    G1 = app.Ch1DataObj.gainDecon';
                    G2 = app.Ch2DataObj.gainDecon';
                    G3 = app.Ch3DataObj.gainDecon';
                    plot(app.UIAxesDeconResult,G1,'.-', 'Color',[131,0,181]/255)
                    hold(app.UIAxesDeconResult,'on')
                    plot(app.UIAxesDeconResult,G2,'.-', 'Color',[0,70,255]/255)
                    plot(app.UIAxesDeconResult,G3,'.-', 'Color',[0,255,146]/255)
                    hold(app.UIAxesDeconResult,'off')
                    legend(app.UIAxesDeconResult,'Ch1','Ch2','Ch3')
                    xlabel(app.UIAxesDeconResult,'Points')
                    ylabel(app.UIAxesDeconResult,'Gain')
                    title(app.UIAxesDeconResult,'Gain Trace Decon Data Points Gain Corrected')
                case 'Raw Waveforms'
                    channel = app.channelDropDown.Value;
                    switch channel
                        case 'Channel 1'
                            plotObj = app.Ch1DataObj;
                        case 'Channel 2'
                            plotObj = app.Ch2DataObj;
                        case 'Channel 3'
                            plotObj = app.Ch3DataObj;
                    end
                    pointIdx = app.Slider.Value;
                    avgedDataIdx = plotObj.deconIdx(pointIdx);
                    rawDataIdx = (avgedDataIdx-1)*plotObj.dataAveraging+1:avgedDataIdx*plotObj.dataAveraging;
                    rawDatatoPlot = plotObj.rawData(:,rawDataIdx);
                    plot(app.UIAxesDeconResult,rawDatatoPlot)
                    lg = cell(plotObj.dataAveraging,1);
                    for i = 1:plotObj.dataAveraging
                        temp = sprintf('WF #%d',i);
                        lg{i} = temp;
                    end
                    legend(app.UIAxesDeconResult,lg)
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             clear variables
            clc
            app.TabGroup.SelectedTab = app.fileSelectionTab;
            setAPDDetectorPath(app)
            loadDeConSettingIni(app)
            app.RemoveSaturationButton.Enable = 0;
            app.AverageDataButton.Enable = 0;
            app.RemoveBGButton.Enable = 0;
            app.UpsampleandTruncateButton.Enable = 0;
            app.DeconButton.Enable = 0;
        end

        % Menu selected function: LoadRawDataMenu
        function LoadRawDataMenuSelected(app, event)
            app.irfAlignFlag = 0;
            [app.dataNameList, folderlist] = creatInputFileList(app.rootFolder);
            app.dataNameList = app.dataNameList';
            app.dataFolder = folderlist{1};
            app.dataFielesListBox.Items = app.dataNameList;
            app.dataFielesListBox.Value = app.dataNameList;
        end

        % Value changed function: bgNotNeededCheckBox
        function bgNotNeededCheckBoxValueChanged(app, event)
            value = app.bgNotNeededCheckBox.Value;
            if value
                app.bgLoadedLamp.Color = 'g';
                app.bgFileEditField.Value = 'Warning: No BG subtraction will be performed!';
                app.bgObj = []; % clear bg from memery
                cla(app.bg3UIAxes)
                cla(app.bg2UIAxes)
                cla(app.bg1UIAxes)
                app.bgLoadedFlag = 1;
            else
                app.bgLoadedLamp.Color = 'r';
                %                 app.bgFileEditField.Value = 'No Selection.';
            end
        end

        % Button pushed function: apd1LoadButton
        function apd1LoadButtonPushed(app, event)
            [filename, foldername] = uigetfile({'*.tdms;*.mat','APD Data Files (*.tdms,*.mat)'}, 'Please Select Channel 1 APD File', app.detectorDataFolder);
            figure(app.UIFigure)
            if filename
                app.detectorDataFolder = foldername;
                app.dataInfoObj.apd1Name = filename;
                app.dataInfoObj.apd1Folder = foldername;
                app.apd1Obj = apdClass(fullfile(foldername,filename));
                app.apd1LoadedLamp.Color = 'g';
                app.apd1LoadedFlag = 1;
                app.apd1FileEditField.Value = app.apd1Obj.filePath;
                creatFromPath(app.apd1Obj);
                app.apd1ViewButton.Enable = 'On'; % enable view button
            else
                app.apd1LoadedLamp.Color = 'r'; % reset indicator
                app.apd1FileEditField.Value = 'Please Select Channel 1 APD File'; % reset prompt
                app.apd1Obj = []; % clear irf field
                app.apd1LoadedFlag = 0; % clear flag
                app.apd1ViewButton.Enable = 'Off'; % enable view button
            end
        end

        % Button pushed function: loadDataButton
        function loadFileButtonPushed(app, event)
            [app.dataNameList, folderlist] = creatInputFileList(app.rootFolder);
            figure(app.UIFigure)
            if ~isempty(app.dataNameList)
                app.dataNameList = app.dataNameList';
                app.dataFolder = folderlist{1};
                app.dataFielesListBox.Items = app.dataNameList;
                app.dataFielesListBox.Value = app.dataNameList;
                app.dataLoadedFlag = 1;
                app.clearButton.Enable = 'On'; % Enable clear button
                uiUpdate(app, 'init')
                clearAllPreviewAxis(app)
                clearAllFittingResultPlot(app) % clear fitting result figures
            else
                app.dataFielesListBox.Items = {'No Data File Selected!'};
                app.dataFielesListBox.Value = {'No Data File Selected!'};
                app.clearButton.Enable = 'Off'; % Disable clear button
            end
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            app.dataFielesListBox.Items = {'No Data file selected!'};
        end

        % Button pushed function: bgLoadButton
        function bgLoadButtonPushed(app, event)
            [filename_BG, foldername_BG] = uigetfile('*.tdms*', 'Please Select the Background tdms file', app.dataFolder);
            figure(app.UIFigure)
            %check whether file selection is valid
            if filename_BG % if valid, load bg
                app.dataInfoObj.bgFileName = filename_BG;
                app.dataInfoObj.bgFileFolder = foldername_BG;
                app.bgObj = backGround(fullfile(foldername_BG,filename_BG));
                loadBG(app.bgObj);
                app.bgFileEditField.Value = app.bgObj.pathToBg;
                plot(app.bg1UIAxes,app.bgObj.bgCh1,'b.--','MarkerSize',8);
                app.bg1UIAxes.YLim = [-0.05 1.5];
                plot(app.bg2UIAxes,app.bgObj.bgCh2,'b.--','MarkerSize',8);
                app.bg2UIAxes.YLim = [-0.05 1.5];
                plot(app.bg3UIAxes,app.bgObj.bgCh3,'b.--','MarkerSize',8);
                app.bg3UIAxes.YLim = [-0.05 1.5];
                app.bgLoadedLamp.Color = 'g';
                app.bgLoadedFlag = 1;
            else % if invalid, reset path indicator and lamp
                app.bgLoadedLamp.Color = 'r';
                app.bgFileEditField.Value = 'Please Select the Background tdms file!';
                app.bgObj = [];
                app.bgLoadedFlag = 0;
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            %             selectedTab = app.TabGroup.SelectedTab;
            % check whether files are all configed.
            if (~app.apd1LoadedFlag || ~app.apd2LoadedFlag || ~app.apd3LoadedFlag || ~app.bgLoadedFlag || ~app.dataLoadedFlag)
                uialert(app.UIFigure,'Files are not set, please make sure all necessary files are selected!','Invalid File');
                app.TabGroup.SelectedTab = app.fileSelectionTab;
            end
            if app.TabGroup.SelectedTab == app.DeConSettingTab
                uiUpdate(app,'init')
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            
            clearAllPreviewAxis(app) % clear all existing data plot
            clearAllFittingResultPlot(app)
            set(app.UIFigure,'pointer','watch') % set cursor to spinning
            drawnow
            app.dataInfoObj.dataFileNames = app.dataFielesListBox.Value;
            app.dataInfoObj.dataFolderName = app.dataFolder;
            app.FLImDataObj = FLImDataClass(fullfile(app.dataFolder,app.dataFielesListBox.Value{1}));
            loadFromfile(app.FLImDataObj)
            
            %--------------populate average selection----------------------
            avg = app.FLImDataObj.dataAvg;
            avg = double(avg);
            AvgSelection = cell(log2(avg)+1,1);
            for i = 1:log2(avg)+1
                AvgSelection{i} = num2str(avg/power(2,i-1));
            end
            app.DataAverageDropDown.Items = AvgSelection;
            app.DataAverageDropDown.Value = AvgSelection{1};
            %--------------------------------------------------------------
            
            app.Ch1DataObj = ChannelDataAPD(app.FLImDataObj.ch1RawWF,app.FLImDataObj.V1, app.FLImDataObj.dataAvg,app.apd1Obj,app.TimeResolutionnsEditField.Value,app.bgObj.bgCh1,app.dataInfoObj.laserRepRate);
            app.Ch2DataObj = ChannelDataAPD(app.FLImDataObj.ch2RawWF,app.FLImDataObj.V2, app.FLImDataObj.dataAvg,app.apd2Obj,app.TimeResolutionnsEditField.Value,app.bgObj.bgCh2,app.dataInfoObj.laserRepRate);
            app.Ch3DataObj = ChannelDataAPD(app.FLImDataObj.ch3RawWF,app.FLImDataObj.V3, app.FLImDataObj.dataAvg,app.apd3Obj,app.TimeResolutionnsEditField.Value,app.bgObj.bgCh3,app.dataInfoObj.laserRepRate);


            plotData(app,'raw')
            plotDcMarker(app)
            plotBgMarker(app)
            
            
            set(app.UIFigure,'pointer','arrow')
            drawnow
            
            uiUpdate(app, 'dataLoaded') % enable UI component
            
        end

        % Value changed function: DcLowEditField
        function DcLowEditFieldValueChanged(app, event)
            plotDcMarker(app)
        end

        % Value changed function: DcHighEditField
        function DcHighEditFieldValueChanged(app, event)
            plotDcMarker(app)
        end

        % Value changed function: BgLowEditField
        function BgLowEditFieldValueChanged(app, event)
            plotBgMarker(app)
        end

        % Value changed function: BgHighEditField
        function BgHighEditFieldValueChanged(app, event)
            plotBgMarker(app)
        end

        % Button pushed function: RemoveBGButton
        function RemoveBGButtonPushed(app, event)
            
            set(app.UIFigure,'pointer','watch') % set cursor to spinning
            drawnow
            
            dcLow = app.DcLowEditField.Value;
            dcHigh = app.DcHighEditField.Value;
            bgLow = app.BgLowEditField.Value;
            bgHigh = app.BgHighEditField.Value;
            removeDCBG(app.Ch1DataObj,dcLow,dcHigh,bgLow,bgHigh);
            removeDCBG(app.Ch2DataObj,dcLow,dcHigh,bgLow,bgHigh);
            removeDCBG(app.Ch3DataObj,dcLow,dcHigh,bgLow,bgHigh);
            plotPreprocessedData(app)
            
            app.UpsampleandTruncateButton.Enable = 1;
            
            set(app.UIFigure,'pointer','arrow') % set cursor to arrow
            drawnow
            uiUpdate(app, 'BGRemoved')
        end

        % Callback function
        function TruncateDataButtonPushed(app, event)
            app.rawDataObj.peaks = [app.CH1EditField.Value app.CH2EditField.Value app.CH3EditField.Value app.CH4EditField.Value];
            truncatedData = truncateData(app.rawDataObj,app.channelWidthEditField.Value);
            app.ChDataArray{1} = ChannelData(truncatedData{1},app.irfObj.ch1Norm,app.TimeResolutionnsEditField.Value,app.DetectorBandwidthGHzEditField.Value,app.rawDataObj.noise,app.rawDataObj.gain);
            app.ChDataArray{2} = ChannelData(truncatedData{2},app.irfObj.ch2Norm,app.TimeResolutionnsEditField.Value,app.DetectorBandwidthGHzEditField.Value,app.rawDataObj.noise,app.rawDataObj.gain);
            app.ChDataArray{3} = ChannelData(truncatedData{3},app.irfObj.ch3Norm,app.TimeResolutionnsEditField.Value,app.DetectorBandwidthGHzEditField.Value,app.rawDataObj.noise,app.rawDataObj.gain);
            app.ChDataArray{4} = ChannelData(truncatedData{4},app.irfObj.ch4Norm,app.TimeResolutionnsEditField.Value,app.DetectorBandwidthGHzEditField.Value,app.rawDataObj.noise,app.rawDataObj.gain);
            app.TabGroup.SelectedTab = app.channelDataTab;
            plotChannelData(app, app.UIAxes_Ch1, app.ChDataArray{1});
            plotChannelData(app, app.UIAxes_Ch2, app.ChDataArray{2});
            plotChannelData(app, app.UIAxes_Ch3, app.ChDataArray{3});
            plotChannelData(app, app.UIAxes_Ch4, app.ChDataArray{4});
        end

        % Callback function
        function AlignIRFButtonPushed(app, event)
            % get channel selection
            app.deconChannelSelection = [app.channel1CheckBox.Value app.channel2CheckBox.Value*2 app.channel3CheckBox.Value*3 app.channel4CheckBox.Value*4];
            app.deconChannelSelection(app.deconChannelSelection==0)=[];
            app.ChLaguerreArray = cell(4,1); % clear cell array that stores the obj
            app.irfAlignFlag = 0; %clear irf alignment flag
            for i=1:4 % assign Laguerre obj to cell array
                app.ChLaguerreArray{i} = LaguerreModel(app.ChDataArray{i});
            end
            % align irf
            for i=app.deconChannelSelection
                iIRF_align(app.ChLaguerreArray{i});
            end
            % plot irf alignment result
            
            plotChannelData(app, app.UIAxes_Ch1, app.ChLaguerreArray{1}.channeldata);
            plotChannelData(app, app.UIAxes_Ch2, app.ChLaguerreArray{2}.channeldata);
            plotChannelData(app, app.UIAxes_Ch3, app.ChLaguerreArray{3}.channeldata);
            plotChannelData(app, app.UIAxes_Ch4, app.ChLaguerreArray{4}.channeldata);
            
            % set irf alignment flag to 1.
            app.irfAlignFlag = 1;
        end

        % Callback function
        function RunDeconButtonPushed(app, event)
            % set decon indicator and string
            app.deConStatusLamp.Color = 'r';
            app.deConStatusLabel.Text = 'Busy';
            drawnow % update UI
            % run decon for selected channel
            for i =app.deconChannelSelection
                if ~app.irfAlignFlag
                    iIRF_align(app.ChLaguerreArray{i});
                end
                app.irfAlignFlag = 1;
                dataThresholding(app.ChLaguerreArray{i},[app.LowThresholdEditField.Value app.HighThresholdEditField.Value]);
                estimate_laguerre(app.ChLaguerreArray{i});
            end
            % reset decon indicator and text
            app.deConStatusLamp.Color = 'g';
            app.deConStatusLabel.Text = 'Idle';
            % display decon done in command window
            disp('Deconvolution Done!')
            % switch to decon fitting overview tab
            app.TabGroup.SelectedTab = app.FittingResultTab;
            % set decon fitting channel selector items
            Items_temp = cell(size(app.deconChannelSelection));
            for i = 1:length(app.deconChannelSelection)
                Items_temp{i} = num2str(app.deconChannelSelection(i));
            end
            app.channelDropDown.Items = Items_temp;
            % plot deconvolusion result on tab
            plotDeconResult(app)
            % creat decon result object that stores all data and reconstruct all images.
            app.deconResutlObj = deconResult(app.rawDataObj,app.ChLaguerreArray);
            %             plotImg(app)
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            % to validate integer value
            value = app.Slider.Value;
            value  = round(value);
            app.Slider.Value = value;
            plotDeconResult(app)
            option = app.PlotDropDown.Value;
            if option=='Raw Waveforms'
                plotTrace(app, option)
            end
            
           
            %             plotImg(app)
        end

        % Value changed function: LineSlider
        function LineSliderValueChanged(app, event)
            value = app.LineSlider.Value;
            value  = round(value);
            app.LineSlider.Value = value;
            plotDeconResult(app)
            %             plotImg(app)
        end

        % Value changed function: channelDropDown
        function channelDropDownValueChanged(app, event)
            plotDeconResult(app)
            %             plotImg(app)
        end

        % Value changed function: xMaxEditField, xMinEditField
        function xMinEditFieldValueChanged(app, event)
            plotDeconResult(app)
            
        end

        % Callback function
        function CH1EditFieldValueChanged(app, event)
            % plop new peak location
            plotPeakLocations(app, [app.CH1EditField.Value app.CH2EditField.Value app.CH3EditField.Value app.CH4EditField.Value]);
        end

        % Button pushed function: apd2LoadButton
        function apd2LoadButtonPushed(app, event)
            [filename, foldername] = uigetfile({'*.tdms;*.mat','APD Data Files (*.tdms,*.mat)'}, 'Please Select Channel 2 APD File', app.detectorDataFolder);
            figure(app.UIFigure)
            if filename
                app.detectorDataFolder = foldername;
                app.dataInfoObj.apd2Name = filename;
                app.dataInfoObj.apd2Folder = foldername;
                app.apd2Obj = apdClass(fullfile(foldername,filename));
                app.apd2LoadedLamp.Color = 'g';
                app.apd2LoadedFlag = 1;
                app.apd2FileEditField.Value = app.apd2Obj.filePath;
                creatFromPath(app.apd2Obj);
                app.apd2ViewButton.Enable = 'On'; % enable view button
            else
                app.apd2LoadedLamp.Color = 'r'; % reset indicator
                app.apd2FileEditField.Value = 'Please Select Channel 2 APD File'; % reset prompt
                app.apd2Obj = []; % clear irf field
                app.apd2LoadedFlag = 0; % clear flag
                app.apd2ViewButton.Enable = 'Off'; % Disable view button
            end
        end

        % Button pushed function: apd3LoadButton
        function apd3LoadButtonPushed(app, event)
            [filename, foldername] = uigetfile({'*.tdms;*.mat','APD Data Files (*.tdms,*.mat)'}, 'Please Select Channel 3 APD File', app.detectorDataFolder);
            figure(app.UIFigure)
            if filename
                app.detectorDataFolder = foldername;
                app.dataInfoObj.apd3Name = filename;
                app.dataInfoObj.apd3Folder = foldername;
                app.apd3Obj = apdClass(fullfile(foldername,filename));
                app.apd3LoadedLamp.Color = 'g';
                app.apd3LoadedFlag = 1;
                app.apd3FileEditField.Value = app.apd3Obj.filePath;
                creatFromPath(app.apd3Obj);
                app.apd3ViewButton.Enable = 'On'; % enable view button
            else
                app.apd3LoadedLamp.Color = 'r'; % reset indicator
                app.apd3FileEditField.Value = 'Please Select Channel 3 APD File'; % reset prompt
                app.apd3Obj = []; % clear irf field
                app.apd3LoadedFlag = 0; % clear flag
                app.apd3ViewButton.Enable = 'Off'; % Disable view button
            end
        end

        % Button pushed function: caliLoadButton
        function caliLoadButtonPushed(app, event)
            [filename, foldername] = uigetfile('*.tdms', 'Please Select System Calibration File', app.dataFolder);
            figure(app.UIFigure)
            if filename
                app.dataInfoObj.caliFileName = filename;
                app.dataInfoObj.caliFileFolder = foldername;
                app.caliObj = sysCaliDataClass(fullfile(foldername,filename));
                app.caliLoadedLamp.Color = 'g';
                load(app.caliObj);
            else
                app.caliLoadedLamp.Color = 'r'; % reset indicator
                app.caliFileEditField.Value = 'Please Select Channel 3 APD File'; % reset prompt
                app.caliObj = []; % clear irf field
                app.caliLoadedFlag = 0; % clear flag
            end
            
        end

        % Button pushed function: apd1ViewButton
        function apd1ViewButtonPushed(app, event)
            app.apd1ViewButton.Enable = 'Off';
            app.apd1DialogApp = appApdViewInfo(app,app.apd1Obj,1);
            
        end

        % Close request function: UIFigure
        function MainAppCloseRequest(app, event)
            saveAPDDetectorPath(app);
            saveDeconSettingIni(app);
            delete(app.apd1DialogApp);
            delete(app.apd2DialogApp);
            delete(app.apd3DialogApp);
            delete(app);
            
        end

        % Button pushed function: apd2ViewButton
        function apd2ViewButtonPushed(app, event)
            app.apd2ViewButton.Enable = 'Off';
            app.apd2DialogApp = appApdViewInfo(app,app.apd2Obj,2);
        end

        % Button pushed function: apd3ViewButton
        function apd3ViewButtonPushed(app, event)
            app.apd3ViewButton.Enable = 'Off';
            app.apd3DialogApp = appApdViewInfo(app,app.apd3Obj,3);
        end

        % Button pushed function: RemoveSaturationButton
        function RemoveSaturationButtonPushed(app, event)
            set(app.UIFigure,'pointer','watch') % set cursor to spinning
            drawnow
            
            [totalNumOfWf, goodNumOfWf, badNumOfWf] = removeSaturation(app.Ch1DataObj,app.LowThresholdEditField.Value,app.HighThresholdEditField.Value);
            msg1 = sprintf('Total number of waveforms: %d.\nWaveforms left: %d.\nWaveforms removed: %d.',...
                totalNumOfWf,goodNumOfWf,badNumOfWf);
            [totalNumOfWf, goodNumOfWf, badNumOfWf] = removeSaturation(app.Ch2DataObj,app.LowThresholdEditField.Value,app.HighThresholdEditField.Value);
            msg2 = sprintf('Total number of waveforms: %d.\nWaveforms left: %d.\nWaveforms removed: %d.',...
                totalNumOfWf,goodNumOfWf,badNumOfWf);
            [totalNumOfWf, goodNumOfWf, badNumOfWf] = removeSaturation(app.Ch3DataObj,app.LowThresholdEditField.Value,app.HighThresholdEditField.Value);
            msg3 = sprintf('Total number of waveforms: %d.\nWaveforms left: %d.\nWaveforms removed: %d.',...
                totalNumOfWf,goodNumOfWf,badNumOfWf);
            msgbox({'Channel 1:';msg1;'';'Channel 2:';msg2;'';'Channel 3:';msg3},'Data info','help')
            plotData(app,'filtered')
            plotDcMarker(app)
            plotBgMarker(app)
            
            app.AverageDataButton.Enable = 1;
            
            set(app.UIFigure,'pointer','arrow') % set cursor to arrow
            drawnow
            uiUpdate(app, 'removedSat')
            
        end

        % Button pushed function: AverageDataButton
        function AverageDataButtonPushed(app, event)
            
            set(app.UIFigure,'pointer','watch') % set cursor to spinning
            drawnow
            
            avg = app.DataAverageDropDown.Value;
            avg = str2double(avg);
            avgData(app.Ch1DataObj, avg)
            avgData(app.Ch2DataObj, avg)
            avgData(app.Ch3DataObj, avg)
            plotData(app,'averaged')
            plotDcMarker(app)
            plotBgMarker(app)
            
            app.RemoveBGButton.Enable = 1;
            
            set(app.UIFigure,'pointer','arrow') % set cursor to arrow
            drawnow
            uiUpdate(app, 'dataAveraged')
            
        end

        % Button pushed function: UpsampleandTruncateButton
        function UpsampleandTruncateButtonPushed(app, event)
            
            set(app.UIFigure,'pointer','watch') % set cursor to spinning
            drawnow
            
            upSampleData(app.Ch1DataObj);
            truncateData(app.Ch1DataObj,app.channelWidthEditField.Value);
            upSampleData(app.Ch2DataObj);
            truncateData(app.Ch2DataObj,app.channelWidthEditField.Value);
            upSampleData(app.Ch3DataObj);
            truncateData(app.Ch3DataObj,app.channelWidthEditField.Value);
            plotTruncatedData(app)
            app.DeconButton.Enable = 1;
            
            set(app.UIFigure,'pointer','arrow') % set cursor to arrow
            drawnow
            uiUpdate(app, 'truncated')
        end

        % Button pushed function: DeconButton
        function DeconButtonPushed(app, event)
            
            set(app.UIFigure,'pointer','watch') % set cursor to spinning
            drawnow
            
            f = waitbar(0,'Processing channel 1...');
            
            runDecon(app.Ch1DataObj);
            
            waitbar(0.33,f,'Processing channel 2...');
            runDecon(app.Ch2DataObj);
            %
            waitbar(0.66,f,'Processing channel 3...');
            runDecon(app.Ch3DataObj);
            
            waitbar(1,f,'Finished');
            
            close(f)
            delete(f)
            
            set(app.UIFigure,'pointer','arrow') % set cursor to arrow
            drawnow
            
            
            app.TabGroup.SelectedTab = app.FittingResultTab;
            clearAllPreviewAxis(app)
            app.Slider.Value = 1;
            updateChannelDropDown(app)
            plotDeconResult(app);
            option = app.PlotDropDown.Value;
            plotTrace(app,option)


        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            
            set(app.UIFigure,'pointer','watch') % set cursor to spining
            drawnow
            
            c = clock; % get current time and date
            saveFolderName = ['Decon ' num2str(c(1)) '-' num2str(c(2)) '-' num2str(c(3)) ' ' num2str(c(4)) '-' num2str(c(5))];
            mkdir(app.dataFolder,saveFolderName);
            [~,saveFileName,~] = fileparts(app.dataNameList{1});
            saveFileName = [saveFileName '.mat'];
            saveFileFullPath = fullfile(app.dataFolder,saveFolderName,saveFileName);
            
            dataInfoObj = app.dataInfoObj;
            
            Ch1DataObj = app.Ch1DataObj;
            Ch2DataObj = app.Ch2DataObj;
            Ch3DataObj = app.Ch3DataObj;


            save(saveFileFullPath, 'dataInfoObj','Ch1DataObj','Ch2DataObj','Ch3DataObj');
            
            set(app.UIFigure,'pointer','arrow') % set cursor to arrow
            drawnow
            
            msgbox('Data Successfully Saved!')
        end

        % Value changed function: PlotDropDown
        function PlotDropDownValueChanged(app, event)
            option = app.PlotDropDown.Value;
            plotTrace(app,option)
        end

        % Value changed function: PointEditField
        function PointEditFieldValueChanged(app, event)
            value = app.PointEditField.Value;
            value = round(value);
            app.Slider.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FLIm Brush Data Processing Tool';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.MainApp = uimenu(app.UIFigure);
            app.MainApp.Text = 'Data';
            app.LoadRawDataMenu = uimenu(app.MainApp);
            app.LoadRawDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadRawDataMenuSelected, true);
            app.LoadRawDataMenu.Text = 'Load Raw Data';
            app.LoadProcessedDataMenu = uimenu(app.MainApp);
            app.LoadProcessedDataMenu.Text = 'Load Processed Data';
            app.SetRootPathMenu = uimenu(app.MainApp);
            app.SetRootPathMenu.Text = 'Set Root Path';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.fileSelectionTab = uitab(app.TabGroup);
            app.fileSelectionTab.Title = 'File Selection';
            app.GridLayout = uigridlayout(app.fileSelectionTab);
            app.caliViewButton = uibutton(app.GridLayout, 'push');
            app.caliViewButton.Enable = 'off';
            app.caliViewButton.Text = 'View';
            app.caliLoadButton = uibutton(app.GridLayout, 'push');
            app.caliLoadButton.ButtonPushedFcn = createCallbackFcn(app, @caliLoadButtonPushed, true);
            app.caliLoadButton.Text = 'Load';
            app.caliLoadedLamp = uilamp(app.GridLayout);
            app.apd3ViewButton = uibutton(app.GridLayout, 'push');
            app.apd3ViewButton.ButtonPushedFcn = createCallbackFcn(app, @apd3ViewButtonPushed, true);
            app.apd3ViewButton.Enable = 'off';
            app.apd3ViewButton.Text = 'View';
            app.apd2ViewButton = uibutton(app.GridLayout, 'push');
            app.apd2ViewButton.ButtonPushedFcn = createCallbackFcn(app, @apd2ViewButtonPushed, true);
            app.apd2ViewButton.Enable = 'off';
            app.apd2ViewButton.Text = 'View';
            app.apd1ViewButton = uibutton(app.GridLayout, 'push');
            app.apd1ViewButton.ButtonPushedFcn = createCallbackFcn(app, @apd1ViewButtonPushed, true);
            app.apd1ViewButton.Enable = 'off';
            app.apd1ViewButton.Text = 'View';
            app.apd3LoadButton = uibutton(app.GridLayout, 'push');
            app.apd3LoadButton.ButtonPushedFcn = createCallbackFcn(app, @apd3LoadButtonPushed, true);
            app.apd3LoadButton.Text = 'Load';
            app.apd3LoadedLamp = uilamp(app.GridLayout);
            app.apd2LoadButton = uibutton(app.GridLayout, 'push');
            app.apd2LoadButton.ButtonPushedFcn = createCallbackFcn(app, @apd2LoadButtonPushed, true);
            app.apd2LoadButton.Text = 'Load';
            app.apd2LoadedLamp = uilamp(app.GridLayout);
            app.clearButton = uibutton(app.GridLayout, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Enable = 'off';
            app.clearButton.Text = 'Clear';
            app.loadDataButton = uibutton(app.GridLayout, 'push');
            app.loadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadFileButtonPushed, true);
            app.loadDataButton.Text = 'Select';
            app.bgNotNeededCheckBox = uicheckbox(app.GridLayout);
            app.bgNotNeededCheckBox.ValueChangedFcn = createCallbackFcn(app, @bgNotNeededCheckBoxValueChanged, true);
            app.bgNotNeededCheckBox.Text = 'BG not needed ';
            app.bgLoadButton = uibutton(app.GridLayout, 'push');
            app.bgLoadButton.ButtonPushedFcn = createCallbackFcn(app, @bgLoadButtonPushed, true);
            app.bgLoadButton.Text = 'Load';
            app.apd1LoadButton = uibutton(app.GridLayout, 'push');
            app.apd1LoadButton.ButtonPushedFcn = createCallbackFcn(app, @apd1LoadButtonPushed, true);
            app.apd1LoadButton.Text = 'Load';
            app.bgLoadedLamp = uilamp(app.GridLayout);
            app.apd1FileEditField = uieditfield(app.GridLayout, 'text');
            app.apd1FileEditField.Editable = 'off';
            app.apd1FileEditField.Value = 'Please Select Channel 1 APD File!';
            app.APD1Label = uilabel(app.GridLayout);
            app.APD1Label.Text = 'APD1';
            app.bgFileEditField = uieditfield(app.GridLayout, 'text');
            app.bgFileEditField.Editable = 'off';
            app.bgFileEditField.Value = 'Please Select Background File!';
            app.BGfileEditFieldLabel = uilabel(app.GridLayout);
            app.BGfileEditFieldLabel.Text = 'BG file:';
            app.apd2FileEditField = uieditfield(app.GridLayout, 'text');
            app.apd2FileEditField.Editable = 'off';
            app.apd2FileEditField.Value = 'Please Select Channel 2 APD File!';
            app.APD2Label = uilabel(app.GridLayout);
            app.APD2Label.Text = 'APD2';
            app.apd3FileEditField = uieditfield(app.GridLayout, 'text');
            app.apd3FileEditField.Editable = 'off';
            app.apd3FileEditField.Value = 'Please Select Channel 3 APD File!';
            app.APD3Label = uilabel(app.GridLayout);
            app.APD3Label.Text = 'APD3';
            app.caliFileEditField = uieditfield(app.GridLayout, 'text');
            app.caliFileEditField.Editable = 'off';
            app.caliFileEditField.Value = 'Please Select System Intensity Calibration!';
            app.CalibrationLabel = uilabel(app.GridLayout);
            app.CalibrationLabel.Text = 'Calibration';
            app.apd1LoadedLamp = uilamp(app.GridLayout);
            app.dataFielesListBox = uilistbox(app.GridLayout);
            app.dataFielesListBox.Items = {'No Data file selected!'};
            app.dataFielesListBox.Multiselect = 'on';
            app.dataFielesListBox.Value = {'No Data file selected!'};
            app.dataFilesLabel = uilabel(app.GridLayout);
            app.dataFilesLabel.Text = 'Data Files';
            app.bg3UIAxes = uiaxes(app.GridLayout);
            title(app.bg3UIAxes, 'Ch3 background')
            xlabel(app.bg3UIAxes, 'Points')
            ylabel(app.bg3UIAxes, 'Voltage(V)')
            app.bg2UIAxes = uiaxes(app.GridLayout);
            title(app.bg2UIAxes, 'Ch2 background')
            xlabel(app.bg2UIAxes, 'Points')
            ylabel(app.bg2UIAxes, 'Voltage(V)')
            app.bg1UIAxes = uiaxes(app.GridLayout);
            title(app.bg1UIAxes, 'Ch1 background')
            xlabel(app.bg1UIAxes, 'Points')
            ylabel(app.bg1UIAxes, 'Voltage(V)')
            app.DeConSettingTab = uitab(app.TabGroup);
            app.DeConSettingTab.Title = 'DeCon Setting';
            app.GridLayout2 = uigridlayout(app.DeConSettingTab);
            app.DeConMethodDropDown = uidropdown(app.GridLayout2);
            app.DeConMethodDropDown.Items = {'Laguerre', 'Exponential', 'Analoge Mean Delay', 'Phasor'};
            app.DeConMethodDropDown.Enable = 'off';
            app.DeConMethodDropDown.Value = 'Laguerre';
            app.DeConMethodDropDownLabel = uilabel(app.GridLayout2);
            app.DeConMethodDropDownLabel.Text = 'DeCon Method';
            app.DatasliderSlider = uislider(app.GridLayout2);
            app.DatasliderSlider.Enable = 'off';
            app.LoadDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.RemoveBGButton = uibutton(app.GridLayout2, 'push');
            app.RemoveBGButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBGButtonPushed, true);
            app.RemoveBGButton.Enable = 'off';
            app.RemoveBGButton.Text = 'Remove BG';
            app.RequiredParametersPanel = uipanel(app.GridLayout2);
            app.RequiredParametersPanel.Title = 'Required Parameters';
            app.SignalConditioningPanel = uipanel(app.RequiredParametersPanel);
            app.SignalConditioningPanel.Title = 'Signal Conditioning';
            app.HighThresholdEditFieldLabel = uilabel(app.SignalConditioningPanel);
            app.HighThresholdEditFieldLabel.Enable = 'off';
            app.HighThresholdEditFieldLabel.Text = 'HighThreshold';
            app.HighThresholdEditField = uieditfield(app.SignalConditioningPanel, 'numeric');
            app.HighThresholdEditField.Limits = [0 1.7];
            app.HighThresholdEditField.Enable = 'off';
            app.HighThresholdEditField.Value = 1.4;
            app.LowThresholdEditFieldLabel = uilabel(app.SignalConditioningPanel);
            app.LowThresholdEditFieldLabel.Enable = 'off';
            app.LowThresholdEditFieldLabel.Text = 'Low Threshold';
            app.LowThresholdEditField = uieditfield(app.SignalConditioningPanel, 'numeric');
            app.LowThresholdEditField.Enable = 'off';
            app.LowThresholdEditField.Value = 0.03;
            app.DataAverageDropDown = uidropdown(app.RequiredParametersPanel);
            app.DataAverageDropDown.Items = {'1'};
            app.DataAverageDropDown.Enable = 'off';
            app.DataAverageDropDown.Value = '1';
            app.DataAverageDropDownLabel = uilabel(app.RequiredParametersPanel);
            app.DataAverageDropDownLabel.Enable = 'off';
            app.DataAverageDropDownLabel.Text = 'Data Average';
            app.BGRemoveIndexPanel = uipanel(app.RequiredParametersPanel);
            app.BGRemoveIndexPanel.Title = 'BG Remove Index';
            app.BgLowEditFieldLabel = uilabel(app.BGRemoveIndexPanel);
            app.BgLowEditFieldLabel.Enable = 'off';
            app.BgLowEditFieldLabel.Text = 'Low';
            app.BgLowEditField = uieditfield(app.BGRemoveIndexPanel, 'numeric');
            app.BgLowEditField.ValueChangedFcn = createCallbackFcn(app, @BgLowEditFieldValueChanged, true);
            app.BgLowEditField.Enable = 'off';
            app.BgLowEditField.Value = 100;
            app.BgHighEditFieldLabel = uilabel(app.BGRemoveIndexPanel);
            app.BgHighEditFieldLabel.Enable = 'off';
            app.BgHighEditFieldLabel.Text = 'High';
            app.BgHighEditField = uieditfield(app.BGRemoveIndexPanel, 'numeric');
            app.BgHighEditField.ValueChangedFcn = createCallbackFcn(app, @BgHighEditFieldValueChanged, true);
            app.BgHighEditField.Enable = 'off';
            app.BgHighEditField.Value = 175;
            app.BgThreholdEditFieldLabel = uilabel(app.BGRemoveIndexPanel);
            app.BgThreholdEditFieldLabel.Enable = 'off';
            app.BgThreholdEditFieldLabel.Text = 'Threhold';
            app.BgThreholdEditField = uieditfield(app.BGRemoveIndexPanel, 'numeric');
            app.BgThreholdEditField.Enable = 'off';
            app.BgThreholdEditField.Value = 0.003;
            app.DCRemoveIndexPanel = uipanel(app.RequiredParametersPanel);
            app.DCRemoveIndexPanel.Title = 'DC Remove Index';
            app.DcLowEditFieldLabel = uilabel(app.DCRemoveIndexPanel);
            app.DcLowEditFieldLabel.Enable = 'off';
            app.DcLowEditFieldLabel.Text = 'Low';
            app.DcLowEditField = uieditfield(app.DCRemoveIndexPanel, 'numeric');
            app.DcLowEditField.RoundFractionalValues = 'on';
            app.DcLowEditField.ValueChangedFcn = createCallbackFcn(app, @DcLowEditFieldValueChanged, true);
            app.DcLowEditField.Enable = 'off';
            app.DcLowEditField.Value = 1;
            app.DcHighEditFieldLabel = uilabel(app.DCRemoveIndexPanel);
            app.DcHighEditFieldLabel.Enable = 'off';
            app.DcHighEditFieldLabel.Text = 'High';
            app.DcHighEditField = uieditfield(app.DCRemoveIndexPanel, 'numeric');
            app.DcHighEditField.RoundFractionalValues = 'on';
            app.DcHighEditField.ValueChangedFcn = createCallbackFcn(app, @DcHighEditFieldValueChanged, true);
            app.DcHighEditField.Enable = 'off';
            app.DcHighEditField.Value = 90;
            app.DigitizerTimingPanel = uipanel(app.RequiredParametersPanel);
            app.DigitizerTimingPanel.Title = 'Digitizer Timing';
            app.TimeResolutionnsEditFieldLabel = uilabel(app.DigitizerTimingPanel);
            app.TimeResolutionnsEditFieldLabel.Enable = 'off';
            app.TimeResolutionnsEditFieldLabel.Text = 'Time Resolution (ns)';
            app.TimeResolutionnsEditField = uieditfield(app.DigitizerTimingPanel, 'numeric');
            app.TimeResolutionnsEditField.Enable = 'off';
            app.TimeResolutionnsEditField.Value = 0.4;
            app.RemoveSaturationButton = uibutton(app.GridLayout2, 'push');
            app.RemoveSaturationButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSaturationButtonPushed, true);
            app.RemoveSaturationButton.Enable = 'off';
            app.RemoveSaturationButton.Text = 'Remove Saturation';
            app.UpsampleandTruncateButton = uibutton(app.GridLayout2, 'push');
            app.UpsampleandTruncateButton.ButtonPushedFcn = createCallbackFcn(app, @UpsampleandTruncateButtonPushed, true);
            app.UpsampleandTruncateButton.Enable = 'off';
            app.UpsampleandTruncateButton.Text = 'Upsample and Truncate';
            app.AverageDataButton = uibutton(app.GridLayout2, 'push');
            app.AverageDataButton.ButtonPushedFcn = createCallbackFcn(app, @AverageDataButtonPushed, true);
            app.AverageDataButton.Enable = 'off';
            app.AverageDataButton.Text = 'Average Data';
            app.DeconButton = uibutton(app.GridLayout2, 'push');
            app.DeconButton.ButtonPushedFcn = createCallbackFcn(app, @DeconButtonPushed, true);
            app.DeconButton.Enable = 'off';
            app.DeconButton.Text = 'Decon';
            app.channelSelectionPanel = uipanel(app.GridLayout2);
            app.channelSelectionPanel.Title = 'Channel Selection';
            app.channel2CheckBox = uicheckbox(app.channelSelectionPanel);
            app.channel2CheckBox.Enable = 'off';
            app.channel2CheckBox.Text = 'Channel 2';
            app.channel2CheckBox.Value = true;
            app.channel3CheckBox = uicheckbox(app.channelSelectionPanel);
            app.channel3CheckBox.Enable = 'off';
            app.channel3CheckBox.Text = 'Channel 3';
            app.channel3CheckBox.Value = true;
            app.channel1CheckBox = uicheckbox(app.channelSelectionPanel);
            app.channel1CheckBox.Enable = 'off';
            app.channel1CheckBox.Text = 'Channel 1';
            app.channel1CheckBox.Value = true;
            app.AlgrithmRelatedPanel = uipanel(app.GridLayout2);
            app.AlgrithmRelatedPanel.Title = 'Algrithm Related';
            app.LaguerreOrderEditFieldLabel = uilabel(app.AlgrithmRelatedPanel);
            app.LaguerreOrderEditFieldLabel.Enable = 'off';
            app.LaguerreOrderEditFieldLabel.Text = 'Laguerre Order';
            app.LaguerreOrderEditField = uieditfield(app.AlgrithmRelatedPanel, 'numeric');
            app.LaguerreOrderEditField.Enable = 'off';
            app.LaguerreOrderEditField.Value = 12;
            app.AlphaValueEditFieldLabel = uilabel(app.AlgrithmRelatedPanel);
            app.AlphaValueEditFieldLabel.Enable = 'off';
            app.AlphaValueEditFieldLabel.Text = 'Alpha Value';
            app.AlphaValueEditField = uieditfield(app.AlgrithmRelatedPanel, 'numeric');
            app.AlphaValueEditField.Enable = 'off';
            app.AlphaValueEditField.Value = 0.916;
            app.AlphaModeDropDownLabel = uilabel(app.AlgrithmRelatedPanel);
            app.AlphaModeDropDownLabel.Enable = 'off';
            app.AlphaModeDropDownLabel.Text = 'Alpha Mode';
            app.AlphaModeDropDown = uidropdown(app.AlgrithmRelatedPanel);
            app.AlphaModeDropDown.Items = {'Auto', 'Manual'};
            app.AlphaModeDropDown.Enable = 'off';
            app.AlphaModeDropDown.Value = 'Manual';
            app.ChannelWidthnsLabel = uilabel(app.AlgrithmRelatedPanel);
            app.ChannelWidthnsLabel.Enable = 'off';
            app.ChannelWidthnsLabel.Text = 'Channel Width (ns)';
            app.channelWidthEditField = uieditfield(app.AlgrithmRelatedPanel, 'numeric');
            app.channelWidthEditField.Limits = [0 Inf];
            app.channelWidthEditField.Editable = 'off';
            app.channelWidthEditField.Enable = 'off';
            app.channelWidthEditField.Value = 54.4;
            app.UIAxes_ch3BgRemoveFig = uiaxes(app.GridLayout2);
            title(app.UIAxes_ch3BgRemoveFig, 'background removed ch3')
            xlabel(app.UIAxes_ch3BgRemoveFig, 'Points')
            ylabel(app.UIAxes_ch3BgRemoveFig, 'Voltage')
            app.UIAxes_ch2BgRemoveFig = uiaxes(app.GridLayout2);
            title(app.UIAxes_ch2BgRemoveFig, 'background removed ch2')
            xlabel(app.UIAxes_ch2BgRemoveFig, 'Points')
            ylabel(app.UIAxes_ch2BgRemoveFig, 'Voltage')
            app.UIAxes_ch3DataFig = uiaxes(app.GridLayout2);
            title(app.UIAxes_ch3DataFig, 'Ch3')
            xlabel(app.UIAxes_ch3DataFig, 'Points')
            ylabel(app.UIAxes_ch3DataFig, 'Voltage')
            app.UIAxes_ch1BgRemoveFig = uiaxes(app.GridLayout2);
            title(app.UIAxes_ch1BgRemoveFig, 'background removed ch1')
            xlabel(app.UIAxes_ch1BgRemoveFig, 'Points')
            ylabel(app.UIAxes_ch1BgRemoveFig, 'Voltage')
            app.UIAxes_ch2DataFig = uiaxes(app.GridLayout2);
            title(app.UIAxes_ch2DataFig, 'Ch2')
            xlabel(app.UIAxes_ch2DataFig, 'Points')
            ylabel(app.UIAxes_ch2DataFig, 'Voltage')
            app.UIAxes_ch1DataFig = uiaxes(app.GridLayout2);
            title(app.UIAxes_ch1DataFig, 'Ch1')
            xlabel(app.UIAxes_ch1DataFig, 'Points')
            ylabel(app.UIAxes_ch1DataFig, 'Voltage')
            app.FittingResultTab = uitab(app.TabGroup);
            app.FittingResultTab.Title = 'Fitting Result';
            app.GridLayout3 = uigridlayout(app.FittingResultTab);
            app.channelDropDown = uidropdown(app.GridLayout3);
            app.channelDropDown.ValueChangedFcn = createCallbackFcn(app, @channelDropDownValueChanged, true);
            app.channelLabel = uilabel(app.GridLayout3);
            app.channelLabel.Text = 'Channel #';
            app.LineSlider = uislider(app.GridLayout3);
            app.LineSlider.Limits = [101 102];
            app.LineSlider.MajorTicks = [101 101.1 101.2 101.3 101.4 101.5 101.6 101.7 101.8 101.9 102];
            app.LineSlider.MajorTickLabels = {'101', '101.1', '101.2', '101.3', '101.4', '101.5', '101.6', '101.7', '101.8', '101.9', '102'};
            app.LineSlider.ValueChangedFcn = createCallbackFcn(app, @LineSliderValueChanged, true);
            app.LineSlider.MinorTicks = [101 101.02 101.04 101.06 101.08 101.1 101.12 101.14 101.16 101.18 101.2 101.22 101.24 101.26 101.28 101.3 101.32 101.34 101.36 101.38 101.4 101.42 101.44 101.46 101.48 101.5 101.52 101.54 101.56 101.58 101.6 101.62 101.64 101.66 101.68 101.7 101.72 101.74 101.76 101.78 101.8 101.82 101.84 101.86 101.88 101.9 101.92 101.94 101.96 101.98 102];
            app.LineSlider.Enable = 'off';
            app.LineSlider.Value = 101;
            app.LineSliderLabel = uilabel(app.GridLayout3);
            app.LineSliderLabel.Enable = 'off';
            app.LineSliderLabel.Text = 'Line';
            app.Slider = uislider(app.GridLayout3);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.SliderLabel = uilabel(app.GridLayout3);
            app.SliderLabel.Text = '';
            app.PlotDropDown = uidropdown(app.GridLayout3);
            app.PlotDropDown.Items = {'Raw Intensity', 'Decon Intensity', 'Gain', 'Raw Waveforms', 'SNR'};
            app.PlotDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotDropDownValueChanged, true);
            app.PlotDropDown.Value = 'Raw Intensity';
            app.imgDropDownLabel = uilabel(app.GridLayout3);
            app.imgDropDownLabel.Enable = 'off';
            app.imgDropDownLabel.Text = 'Plot:';
            app.xMinEditField = uieditfield(app.GridLayout3, 'numeric');
            app.xMinEditField.ValueChangedFcn = createCallbackFcn(app, @xMinEditFieldValueChanged, true);
            app.xMinEditFieldLabel = uilabel(app.GridLayout3);
            app.xMinEditFieldLabel.Text = 'xMin';
            app.xMaxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.xMaxEditField.ValueChangedFcn = createCallbackFcn(app, @xMinEditFieldValueChanged, true);
            app.xMaxEditField.Value = 50;
            app.xMaxEditFieldLabel = uilabel(app.GridLayout3);
            app.xMaxEditFieldLabel.Text = 'xMax';
            app.SaveDataButton = uibutton(app.GridLayout3, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.UIAxesFitting = uiaxes(app.GridLayout3);
            title(app.UIAxesFitting, 'Fitting')
            xlabel(app.UIAxesFitting, 'Time(ns)')
            ylabel(app.UIAxesFitting, 'Y')
            app.UIAxesResidue = uiaxes(app.GridLayout3);
            title(app.UIAxesResidue, 'Residue')
            ylabel(app.UIAxesResidue, 'Y')
            app.UIAxesAutoCo = uiaxes(app.GridLayout3);
            title(app.UIAxesAutoCo, 'Auto Correlation')
            ylabel(app.UIAxesAutoCo, 'Y')
            app.UIAxesDeconResult = uiaxes(app.GridLayout3);
            title(app.UIAxesDeconResult, 'BG Subtraction')
            xlabel(app.UIAxesDeconResult, 'Time(ns)')
            ylabel(app.UIAxesDeconResult, 'Y')
            app.PointEditFieldLabel = uilabel(app.GridLayout3);
            app.PointEditFieldLabel.Text = 'Point';
            app.PointEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PointEditField.ValueChangedFcn = createCallbackFcn(app, @PointEditFieldValueChanged, true);
            app.imagesReconstrctionTab = uitab(app.TabGroup);
            app.imagesReconstrctionTab.Title = 'Images Reconstrction';
            app.UIAxesImg = uiaxes(app.imagesReconstrctionTab);
            title(app.UIAxesImg, 'FLIm Images')
            app.EditFieldLabel = uilabel(app.imagesReconstrctionTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.imagesReconstrctionTab, 'text');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.SliderLabel.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainTriplexGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end